classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        ButtonGroup_Selection         matlab.ui.container.ButtonGroup
        Histogram                     matlab.ui.control.ToggleButton
        Contrast                      matlab.ui.control.ToggleButton
        Transform                     matlab.ui.control.ToggleButton
        Button_6                      matlab.ui.control.ToggleButton
        Button_5                      matlab.ui.control.ToggleButton
        Button_4                      matlab.ui.control.ToggleButton
        ButtonGroup_Contrast          matlab.ui.container.ButtonGroup
        Contrast_Linear               matlab.ui.control.ToggleButton
        Contrast_Log                  matlab.ui.control.ToggleButton
        Contrast_Index                matlab.ui.control.ToggleButton
        ButtonGroup_Histogram         matlab.ui.container.ButtonGroup
        Histogram_Origin              matlab.ui.control.ToggleButton
        Histogram_Equalization        matlab.ui.control.ToggleButton
        Histogram_Perspective         matlab.ui.control.ToggleButton
        Input_Label                   matlab.ui.control.Label
        Panel_Transform               matlab.ui.container.Panel
        Transform_Shrinkx             matlab.ui.control.NumericEditField
        Transform_Shrinky             matlab.ui.control.NumericEditField
        Button_Input                  matlab.ui.control.Button
        Button_Transform_Confirm      matlab.ui.control.Button
        Input_Histogram_ReferImage    matlab.ui.control.EditField
        Input_Image                   matlab.ui.control.EditField
        Transform_Rotate1             matlab.ui.control.NumericEditField
        Transform_Rotate2             matlab.ui.control.NumericEditField
        EditField_1                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        UIAxes_Image                  matlab.ui.control.UIAxes
        UIAxes_Histogram_Image        matlab.ui.control.UIAxes
        UIAxes_Histogram_Histogram    matlab.ui.control.UIAxes
        UIAxes_Contrast_Grayimage     matlab.ui.control.UIAxes
        UIAxes_Contrast_Advanceimage  matlab.ui.control.UIAxes
        UIAxes_Transform_Image        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img = imread("E:\course\Digital Image Processing\work\image.png")% 原始图像
        img_gray % 灰度图
        
        histogram % 灰度直方图
        histogram_equalized % 均衡化直方图
        histogram_perspected % 规定化直方图
        img_equalized % 均衡化图像
        img_perspected % 规定化图像
        
        img_advancedlinear % 线性变化增强图像
        img_advancedlog % 对数变化增强图像
        img_advancedindex % 指数变化增强图像_
        
        img_transformed % 几何变换图像
    end


    methods (Access = private)
        
        % 灰度图旋转
        function rotated_image = ImRotation(~,image, angle)
            [ h, w ] = size( image );
            rotated_image = uint8( zeros( h, w ) );
            RotMatrix = [ cos(angle), -sin(angle); sin(angle), cos(angle) ]; % 旋转矩阵
            halfH = floor( h / 2 );
            halfW = floor( w / 2 );
            % 逐个像素计算其旋转前的位置及像素
            for i = 1 : h
                for j = 1 : w
                    coord = [ i-halfH, j-halfW ] * RotMatrix; % 中心化旋转
                    ir = round( coord( 1 ) ) + halfH; 
                    jr = round( coord( 2 ) ) + halfW; 
                    if ir > 0 && ir <= h && jr > 0  && jr <= w
                        rotated_image( i, j ) = image( ir, jr ); % 按照最近邻插值法获取图像数据
                    end
                end
            end
        end
        
        % 重置所有组件
        function [] = reflush(app)
            reflush_histogram(app,0);
            reflush_contrast(app,0);
            reflush_transform(app,0);
        end
        
        % 重置直方图相关组件
        function [] = reflush_histogram(app,flag)
            if(~flag)
                app.ButtonGroup_Histogram.Visible = false;
                app.Input_Histogram_ReferImage.Visible = false;
                app.UIAxes_Histogram_Image.Visible = false;
                app.UIAxes_Histogram_Histogram.Visible = false;
                % 清空窗口输出
                cla(app.UIAxes_Histogram_Image);
                cla(app.UIAxes_Histogram_Histogram);
            else
                app.ButtonGroup_Histogram.Visible = true;
                app.Input_Histogram_ReferImage.Visible = true;
                app.UIAxes_Histogram_Image.Visible = true;
                app.UIAxes_Histogram_Histogram.Visible = true;
            end
        end
        
        % 重置对比度相关组件
        function [] = reflush_contrast(app,flag)
            if(~flag)
                app.ButtonGroup_Contrast.Visible = false;
                app.UIAxes_Contrast_Grayimage.Visible = false;
                app.UIAxes_Contrast_Advanceimage.Visible = false;
                
                cla(app.UIAxes_Contrast_Grayimage);
                cla(app.UIAxes_Contrast_Advanceimage);
            else
                app.ButtonGroup_Contrast.Visible = true;
                app.UIAxes_Contrast_Grayimage.Visible = true;
                app.UIAxes_Contrast_Advanceimage.Visible = true;
            end
        end
        
        % 重置几何变换相关组件
        function [] = reflush_transform(app,flag)
            if(~flag)
                app.Panel_Transform.Visible = false;
                app.Button_Transform_Confirm.Visible = false;
                app.UIAxes_Transform_Image.Visible = false;
                cla(app.UIAxes_Transform_Image);
            else
                app.Panel_Transform.Visible = true;
                app.Button_Transform_Confirm.Visible = true;
                app.UIAxes_Transform_Image.Visible = true;
            end
        end

    end
    
    methods (Access = public)
        
        % 灰度处理
        function image_gray = to_gray(~,image) 
            % r = image(:,:,1);% 分颜色通道提取数据  
            % g = image(:,:,2);
            % b = image(:,:,3);
            % Image_Gray = 0.299*r + 0.587*g + 0.114*b;% 加权处理后累加
            image_gray = rgb2gray(image);
        end
        
        % 灰度直方图
        function histogram = to_histogram(~,gray_image)
            histogram = zeros(256);
            [h,w] = size(gray_image);
            for x = 1:w
                for y = 1:h
                    histogram(gray_image(y,x)+1) = histogram(gray_image(y,x)+1)+1;
                end
            end
        end
        
        % 直方图均衡化
        function equal_image = to_histogram_equalized(~,gray_image)
            histgram = imhist(gray_image);
            [h,w] = size(gray_image);
            New_Image = zeros(h,w);
            s = zeros(256);
            s(1) =histgram(1);
            
            for t = 2:256
                s(t) = s(t-1) + histgram(t); % 计算新的灰度值
            end
            
            for x = 1:w
                for y= 1:h
                    New_Image(y,x) = s(gray_image(y,x)+1) / (w*h); % 生成新图像
                end
            end
            New_Image = uint8(New_Image*255);
            
            equal_image = New_Image;
        end
        
        % 直方图规定化
        function persp_image = to_histogram_perspected(~,gray_image,ref_image)
            input_img = gray_image; 
            ref_img = ref_image;     
            
            % 计算输入图像和参考图像的直方图
            [input_counts, ~] = imhist(input_img, 256);
            [ref_counts, ref_bins] = imhist(ref_img, 256);
            
            % 累加直方图以得到累积分布函数
            input_cdf = cumsum(input_counts) / numel(input_img);
            ref_cdf = cumsum(ref_counts) / numel(ref_img);
            
            % 将输入图像的像素值映射到新的像素值
            table = zeros(1, 256);
            j = 1;
            for i = 0:255  
                while j <= 256 && ref_cdf(j) < input_cdf(i+1)
                    j = j + 1;
                end
                if j > 256
                    j = 256;  
                end
                table(i+1) = ref_bins(j-1);  
            end
            
            persp_image = uint8(table(input_img + 1)); 
        end
        
        % 线性变换对比度增强
        function advanced_image = to_enhanced_linear(~,gray_image)
            [h,w]=size(gray_image);
            advanced_image = zeros(h,w);
            % 规定线性系数
            a=100; b=200; mf=255;
            c=50; d=230; mg=255;
            
            for i = 1:h
                for j = 1:w
                    if(gray_image(i,j)<a && gray_image(i,j)>=0)
                        advanced_image(i,j) = round(c*gray_image(i,j)/a);
                    elseif(gray_image(i,j)<b && gray_image(i,j)>=a)
                        advanced_image(i,j) = round(((d-c)/(b-a))*(gray_image(i,j)-a)+c);
                    elseif(gray_image(i,j)<=mf && gray_image(i,j)>=b)
                        advanced_image(i,j) = round(((mg-d)/(mf-b))*(gray_image(i,j)-b)+d);
                    end
                end
            end
            advanced_image = uint8(advanced_image);
        end
        
        % 对数变换对比度增强
        function advanced_image = to_enhanced_log(~,gray_image)
            image = double(gray_image);
            advanced_image = log(image+1);
        end
        
        % 指数变换对比度增强
        function advanced_image = to_enhanced_index(~,gray_image)
            image = double(gray_image);
            advanced_image = exp(0.325 * (image-255)/30) + 1;
        end
        
        % 几何变换放缩
        function shrinked_image = to_transform_shrinked(~,image,m,n)
            imgR = image(:, :, 1);
            [h, w] = size(imgR);
            
            for t = 1:3
                for i = 1:h*m          %新高度，即行
                    for j = 1:w*n      %新宽度，即列
                        % 坐标转换
                        x = i/m;         
                        y = j/n;           
                        u = x-floor(x);  
                        v = y-floor(y);
                        % 边界处理
                        if x < 1           
                            x = 1;
                        end
                        if x > h
                            x = h;
                        end
                        if y < 1
                            y = 1;
                        end
                        if y > w
                            y = w;
                        end
                        %按权重进行双线性插值
                        shrinked(i,j,t) = image(floor(x), floor(y), t)*(1-u)*(1-v)+ ...
                                    image(floor(x), ceil(y), t)* (1-u) * v + ...
                                    image(ceil(x), floor(y), t)* u * (1-v) + ...
                                    image(ceil(x), ceil(y), t)* u * v;        
                    end
                end
            end
            shrinked_image = shrinked;
        end
        
        % 图像逆时针旋转
        function rotated_image = to_transform_rotated(app,image,angle)
            imSize = size(image);
            R = image( :, :, 1 );
            G = image( :, :, 2 );
            B = image( :, :, 3 );
            
            imR = ImRotation(app,R,angle);
            imG = ImRotation(app,G,angle);
            imB = ImRotation(app,B,angle);
            
            im = uint8(zeros(imSize));
            im( :, :, 1 ) = imR;
            im( :, :, 2 ) = imG;
            im( :, :, 3 ) = imB;
            rotated_image = im;
        end
            
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            % 获取文本框中的路径
            filePath = "E:\course\Digital Image Processing\work\image.png";
            % 检查路径是否为空或无效
            if isempty(filePath) || ~exist(filePath, 'file')
                uialert(app.UIFigure, 'Error', 'Please enter a valid file path.');
                return;
            end
            
            % 读取图片
            try
                image = imread(filePath);
            catch ME %#ok<NASGU> 
                uialert(app.UIFigure, 'Error', 'Failed to read the image file.');
                return;
            end
            
            % 存储原始图片
            app.img = image;
            app.img_gray = rgb2gray(image);
            % 在UIAxes上显示图片
            imshow(app.img, 'Parent', app.UIAxes_Image);
            title(app.UIAxes_Image, '原始图像');
        end

        % Selection changed function: ButtonGroup_Selection
        function ButtonGroup_SelectionSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Selection.SelectedObject;
            if(isempty(app.img) || isempty(app.img_gray))% 处理保护
                uialert(app.UIFigure, 'Error', 'Please enter the image file.')
            end
            reflush(app);
            
            switch selected_button
                case app.Histogram % 灰度直方图
                    reflush_histogram(app,1);      
                    
                case app.Contrast % 对比度增强
                    reflush_contrast(app,1);
                    
                case app.Transform % 几何变换
                    reflush_transform(app,1);
                    
            end
        end

        % Selection changed function: ButtonGroup_Histogram
        function ButtonGroup_HistogramSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Histogram.SelectedObject;
 
            switch selected_button
                case app.Histogram_Equalization % 直方图均衡化
                    if(isempty(app.histogram_equalized))
                        app.img_equalized = to_histogram_equalized(app,app.img_gray);
                        app.histogram_equalized = imhist(app.img_equalized);
                    end
                    
                    % 显示选项栏和图片
                    imshow(app.img_equalized, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '均衡化——灰度图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram_equalized)
                    title(app.UIAxes_Histogram_Histogram, '均衡化——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
                    
                case app.Histogram_Perspective % 直方图规定化
                    img_ref = rgb2gray(imread("E:\course\Digital Image Processing\test\2.png")); %(app.Input_Histogram_ReferImage.Value);
                    if(isempty(app.histogram_perspected))
                        app.img_perspected = to_histogram_perspected(app,app.img_gray,img_ref);
                        app.histogram_perspected = imhist(app.img_perspected);
                    end
                    % 显示选项栏和图片 
                    imshow(app.img_perspected, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '规定化——参考图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram_perspected);
                    title(app.UIAxes_Histogram_Histogram, '规定化——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
                    
                case app.Histogram_Origin % 灰度直方图
                    if(isempty(app.histogram))
                        app.histogram = to_histogram(app,app.img_gray);
                    end
                    
                    % 显示选项栏和图片
                    imshow(app.img_gray, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '灰度图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram)
                    title(app.UIAxes_Histogram_Histogram, '灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
            end
            
        end

        % Selection changed function: ButtonGroup_Contrast
        function ButtonGroup_ContrastSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Contrast.SelectedObject;
            
            switch selected_button
                case app.Contrast_Linear % 线性
                    if(isempty(app.img_advancedlinear))
                        app.img_advancedlinear = to_enhanced_linear(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedlinear, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '线性增强——灰度图'); 
                    
                case app.Contrast_Log % 对数
                    if(isempty(app.img_advancedlog))
                        app.img_advancedlog = to_enhanced_log(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedlog, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '对数增强——灰度图'); 
                    
                case app.Contrast_Index % 指数
                    if(isempty(app.img_advancedindex))
                        app.img_advancedindex = to_enhanced_linear(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedindex, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '指数增强——灰度图'); 
            end
            
        end

        % Button pushed function: Button_Transform_Confirm
        function Button_Transform_ConfirmPushed(app, event)
            m = app.Transform_Shrinkx.Value;
            n = app.Transform_Shrinky.Value;
            angle1 = app.Transform_Rotate1.Value;
            angle2 = app.Transform_Rotate2.Value;
            angle = (angle2-angle1) / 180;
            img_tr = to_transform_shrinked(app, app.img, m, n);
            app.img_transformed = to_transform_rotated(app, img_tr, angle);
            
            imshow(app.img_transformed, 'Parent', app.UIAxes_Transform_Image);
            title(app.UIAxes_Transform_Image, '图像———几何变换');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.UIAxes_Transform_Image = uiaxes(app.UIFigure);
            app.UIAxes_Transform_Image.XTick = [];
            app.UIAxes_Transform_Image.YTick = [];
            app.UIAxes_Transform_Image.Visible = 'off';
            app.UIAxes_Contrast_Advanceimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Advanceimage, '变换后灰度图')
            app.UIAxes_Contrast_Advanceimage.XTick = [];
            app.UIAxes_Contrast_Advanceimage.YTick = [];
            app.UIAxes_Contrast_Advanceimage.Visible = 'off';
            app.UIAxes_Contrast_Grayimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Grayimage, '灰度图')
            app.UIAxes_Contrast_Grayimage.XTick = [];
            app.UIAxes_Contrast_Grayimage.YTick = [];
            app.UIAxes_Contrast_Grayimage.Visible = 'off';
            app.UIAxes_Histogram_Histogram = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Histogram, '直方图')
            app.UIAxes_Histogram_Histogram.XTick = [];
            app.UIAxes_Histogram_Histogram.YTick = [];
            app.UIAxes_Histogram_Histogram.Visible = 'off';
            app.UIAxes_Histogram_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Image, '灰度图')
            app.UIAxes_Histogram_Image.XTick = [];
            app.UIAxes_Histogram_Image.YTick = [];
            app.UIAxes_Histogram_Image.Visible = 'off';
            app.UIAxes_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Image, '原图')
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            app.Input_Image = uieditfield(app.UIFigure, 'text');
            app.Input_Histogram_ReferImage = uieditfield(app.UIFigure, 'text');
            app.Input_Histogram_ReferImage.Visible = 'off';
            app.Input_Histogram_ReferImage.Placeholder = '请输入参照图片地址……';
            app.Button_Transform_Confirm = uibutton(app.UIFigure, 'push');
            app.Button_Transform_Confirm.ButtonPushedFcn = createCallbackFcn(app, @Button_Transform_ConfirmPushed, true);
            app.Button_Transform_Confirm.Visible = 'off';
            app.Button_Transform_Confirm.Text = '确认';
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Text = '上传';
            app.Panel_Transform = uipanel(app.UIFigure);
            app.Panel_Transform.Title = '输入：';
            app.Panel_Transform.Visible = 'off';
            app.EditField_4 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = '逆时针旋转：';
            app.EditField_3 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = '顺时针旋转：';
            app.EditField_2 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '列缩放倍数：';
            app.EditField_1 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Value = '行缩放倍数：';
            app.Transform_Rotate2 = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Rotate1 = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinky = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinky.Value = 1;
            app.Transform_Shrinkx = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinkx.Value = 1;
            app.Input_Label = uilabel(app.UIFigure);
            app.Input_Label.Text = '图片地址：';
            app.ButtonGroup_Histogram = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Histogram.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_HistogramSelectionChanged, true);
            app.ButtonGroup_Histogram.Title = '选择：';
            app.ButtonGroup_Histogram.Visible = 'off';
            app.Histogram_Perspective = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Perspective.Tag = 'Equalization';
            app.Histogram_Perspective.Text = '直方图规定化';
            app.Histogram_Equalization = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Equalization.Tag = 'Equalization';
            app.Histogram_Equalization.Text = '直方图均衡化';
            app.Histogram_Origin = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Origin.Tag = 'Origin';
            app.Histogram_Origin.Text = '灰度图';
            app.Histogram_Origin.Value = true;
            app.ButtonGroup_Contrast = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Contrast.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_ContrastSelectionChanged, true);
            app.ButtonGroup_Contrast.Title = '选择：';
            app.ButtonGroup_Contrast.Visible = 'off';
            app.Contrast_Index = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Index.Tag = 'Equalization';
            app.Contrast_Index.Text = '指数变换';
            app.Contrast_Log = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Log.Tag = 'Equalization';
            app.Contrast_Log.Text = '对数变换';
            app.Contrast_Linear = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Linear.Tag = 'Origin';
            app.Contrast_Linear.Text = '线性处理';
            app.Contrast_Linear.Value = true;
            app.ButtonGroup_Selection = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Selection.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SelectionSelectionChanged, true);
            app.ButtonGroup_Selection.Title = '选择处理方式：';
            app.Button_4 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_4.Text = '噪声处理';
            app.Button_5 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_5.Text = '边缘提取';
            app.Button_6 = uitogglebutton(app.ButtonGroup_Selection);
            app.Button_6.Text = '特征提取';
            app.Transform = uitogglebutton(app.ButtonGroup_Selection);
            app.Transform.Text = '几何变换';
            app.Contrast = uitogglebutton(app.ButtonGroup_Selection);
            app.Contrast.Text = '对比度增强';
            app.Histogram = uitogglebutton(app.ButtonGroup_Selection);
            app.Histogram.Text = {'灰度直方图'; ''};
            app.Histogram.Value = true;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end