classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Toolbar                       matlab.ui.container.Toolbar
        PushTool                      matlab.ui.container.toolbar.PushTool
        TextArea_ML2                  matlab.ui.control.TextArea
        TextArea_ML                   matlab.ui.control.TextArea
        Image_ML                      matlab.ui.control.Image
        ButtonGroup_Selection         matlab.ui.container.ButtonGroup
        Histogram                     matlab.ui.control.ToggleButton
        Contrast                      matlab.ui.control.ToggleButton
        Transform                     matlab.ui.control.ToggleButton
        Filtering                     matlab.ui.control.ToggleButton
        Extract                       matlab.ui.control.ToggleButton
        Feature                       matlab.ui.control.ToggleButton
        Mlrecognition                 matlab.ui.control.ToggleButton
        ButtonGroup_Contrast          matlab.ui.container.ButtonGroup
        Contrast_Linear               matlab.ui.control.ToggleButton
        Contrast_Log                  matlab.ui.control.ToggleButton
        Contrast_Index                matlab.ui.control.ToggleButton
        ButtonGroup_Histogram         matlab.ui.container.ButtonGroup
        Histogram_Origin              matlab.ui.control.ToggleButton
        Histogram_Equalization        matlab.ui.control.ToggleButton
        Histogram_Perspective         matlab.ui.control.ToggleButton
        Input_Label                   matlab.ui.control.Label
        Panel_Transform               matlab.ui.container.Panel
        Transform_Shrinkx             matlab.ui.control.NumericEditField
        Transform_Shrinky             matlab.ui.control.NumericEditField
        Transform_Rotate1             matlab.ui.control.NumericEditField
        Transform_Rotate2             matlab.ui.control.NumericEditField
        EditField_1                   matlab.ui.control.EditField
        EditField_2                   matlab.ui.control.EditField
        EditField_3                   matlab.ui.control.EditField
        EditField_4                   matlab.ui.control.EditField
        Button_Transform_Confirm      matlab.ui.control.Button
        ButtonGroup_Filtering         matlab.ui.container.ButtonGroup
        Filtering_Gaussian            matlab.ui.control.RadioButton
        Filtering_Salt                matlab.ui.control.RadioButton
        Filtering_Speckle             matlab.ui.control.RadioButton
        ButtonGroup_Filtering_2       matlab.ui.container.ButtonGroup
        Filtering_Imbilatfile         matlab.ui.control.RadioButton
        Filtering_Ffshift             matlab.ui.control.RadioButton
        Button_Input                  matlab.ui.control.Button
        ButtonGroup_Extract_Obj       matlab.ui.container.ButtonGroup
        Obj_Adapt                     matlab.ui.control.RadioButton
        Obj_Otsu                      matlab.ui.control.RadioButton
        Obj_Iterative                 matlab.ui.control.RadioButton
        Obj_Obscure                   matlab.ui.control.RadioButton
        Input_Image                   matlab.ui.control.EditField
        Button_Filtering_Confirm      matlab.ui.control.Button
        ButtonGroup_Extract_Edge      matlab.ui.container.ButtonGroup
        LaplacianButton               matlab.ui.control.RadioButton
        SobelButton                   matlab.ui.control.RadioButton
        PrewittButton                 matlab.ui.control.RadioButton
        RobertButton                  matlab.ui.control.RadioButton
        ButtonGroup_Feature           matlab.ui.container.ButtonGroup
        Feature_LBP                   matlab.ui.control.RadioButton
        Feature_HOG                   matlab.ui.control.RadioButton
        Input_Histogram_ReferImage    matlab.ui.control.EditField
        Input_Filtering_Label         matlab.ui.control.EditField
        Input_Filtering               matlab.ui.control.NumericEditField
        UIAxes_Image                  matlab.ui.control.UIAxes
        UIAxes_Histogram_Image        matlab.ui.control.UIAxes
        UIAxes_Histogram_Histogram    matlab.ui.control.UIAxes
        UIAxes_Contrast_Grayimage     matlab.ui.control.UIAxes
        UIAxes_Contrast_Advanceimage  matlab.ui.control.UIAxes
        UIAxes_Transform_Image        matlab.ui.control.UIAxes
        UIAxes_Filtering_Noise        matlab.ui.control.UIAxes
        UIAxes_Filtering              matlab.ui.control.UIAxes
        UIAxes_Extract_Edge           matlab.ui.control.UIAxes
        UIAxes_Extract_Object         matlab.ui.control.UIAxes
        UIAxes_Feature                matlab.ui.control.UIAxes
        UIAxes_Feature2               matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % 原始图像
        img_gray % 灰度图
        
        histogram % 灰度直方图
        histogram_equalized % 均衡化直方图
        histogram_perspected % 规定化直方图
        img_equalized % 均衡化图像
        img_perspected % 规定化图像
        
        img_advancedlinear % 线性变化增强图像
        img_advancedlog % 对数变化增强图像
        img_advancedindex % 指数变化增强图像_
        
        img_transformed % 几何变换图像
        
        img_noise % 噪声图像
        img_filtering % 滤波图像
        
        img_edge % 边缘提取
        img_obj % 目标提取
        
        img_LBP % 特征提取LBP
        img_LBPedge 
        img_HOG % 特征提取HOG
        img_HOGedge
    end
    
    methods (Access = private)
        
        % 灰度图旋转
        function rotated_image = ImRotation(~,image, angle)
            [ h, w ] = size( image );
            rotated_image = uint8( zeros( h, w ) );
            RotMatrix = [ cos(angle), -sin(angle); sin(angle), cos(angle) ]; % 旋转矩阵
            halfH = floor( h / 2 );
            halfW = floor( w / 2 );
            % 逐个像素计算其旋转前的位置及像素
            for i = 1 : h
                for j = 1 : w
                    coord = [ i-halfH, j-halfW ] * RotMatrix; % 中心化旋转
                    ir = round( coord( 1 ) ) + halfH; 
                    jr = round( coord( 2 ) ) + halfW; 
                    if ir > 0 && ir <= h && jr > 0  && jr <= w
                        rotated_image( i, j ) = image( ir, jr ); % 按照最近邻插值法获取图像数据
                    end
                end
            end
        end
        
        % 调用py模块
        function classes = load_classes(~,yaml_file)
                % 加载Python模块
                py.importlib.import_module('yaml');
               
                % 使用py.io.open读取yaml文件
                file_id = py.io.open(yaml_file, 'r');  % 打开文件对象
                yaml_data = py.yaml.safe_load(file_id);  % 使用safe_load而非load, 这个方法不需要指定Loader
                
                % 关闭文件对象
                file_id.close();
                classes = yaml_data{'names'}; 
        end
        
        % 对图像进行预处理以适应模型的输入要求
        function img_data = preprocess_image(~,img)
                % 假设输入大小为 (3, 640, 640)
                [img_height, img_width, ~] = size(img);
                
                % 将图像调整为匹配输入形状 (640, 640, 3)
                img = imresize(img, [640, 640]); 
                img = single(img) / 255.0;  % 归一化
                
                % 转换为通道优先的格式 (C, H, W)
                img_data = permute(img, [3, 1, 2]);  % 将 (H, W, C) 转换为 (C, H, W)
                
                % 扩展维度为 (1, C, H, W) 以匹配模型的输入格式
                img_data = reshape(img_data, [1, size(img_data)]);
        end
        
        % 模型匹配
        function targetId = get_class_ids(~,outputs, confT)
            output = outputs{1};
            disp(class(output));
            output = py.numpy.squeeze(output);  % 移除单维度
            output_trans = py.numpy.transpose(output);
            output_list = output_trans.tolist();  % 使用 .tolist() 将其转换为 Python list
        
            % 获取输出的行数（即检测框的数量）
            num_boxes = double(py.len(output_list));  % 获取输出的行数，即检测框的数量
            class_ids = [];
            scores = [];
        
            % 遍历所有检测框
            for i = 1:num_boxes
                % 使用 MATLAB 的圆括号访问 Python list 中的第 i 行
                row = double(py.array.array('d', output_list{i})); 
        
                scores_row  = row(5:end);  % 取出从第 5 列开始的得分
                [max_score, class_id] = max(scores_row);  % 找到最大得分及对应的类别 ID（MATLAB 中的索引从 1 开始）
        
                % 判断最大得分是否大于或等于置信度阈值
                if max_score >= confT
                    class_ids = [class_ids; class_id - 1];  
                    scores = [scores; max_score];
                end
            end
        
            targetId = mode(class_ids);
            if isempty(targetId)
                targetId = NaN; 
            end
        end
        
        % 重置所有组件
        function [] = reflush(app)
            reflush_histogram(app,0);
            reflush_contrast(app,0);
            reflush_transform(app,0);
            reflush_filtering(app,0);
            reflush_edge(app,0);
            reflush_feature(app,0);
            reflush_ML(app,0);
        end
        
        % 重置直方图相关组件
        function [] = reflush_histogram(app,flag)
            if(~flag)
                app.ButtonGroup_Histogram.Visible = false;
                app.Input_Histogram_ReferImage.Visible = false;
                app.UIAxes_Histogram_Image.Visible = false;
                app.UIAxes_Histogram_Histogram.Visible = false;
                % 清空窗口输出
                cla(app.UIAxes_Histogram_Image);
                cla(app.UIAxes_Histogram_Histogram);
                title(app.UIAxes_Histogram_Image, '');
                title(app.UIAxes_Histogram_Histogram, '');
            else
                app.ButtonGroup_Histogram.Visible = true;
                app.Input_Histogram_ReferImage.Visible = true;
                app.UIAxes_Histogram_Image.Visible = true;
                app.UIAxes_Histogram_Histogram.Visible = true;
            end
        end
        
        % 重置对比度相关组件
        function [] = reflush_contrast(app,flag)
            if(~flag)
                app.ButtonGroup_Contrast.Visible = false;
                app.UIAxes_Contrast_Grayimage.Visible = false;
                app.UIAxes_Contrast_Advanceimage.Visible = false;
                cla(app.UIAxes_Contrast_Grayimage);
                cla(app.UIAxes_Contrast_Advanceimage);
                title(app.UIAxes_Contrast_Grayimage, '');
                title(app.UIAxes_Contrast_Advanceimage, '');
            else
                app.ButtonGroup_Contrast.Visible = true;
                app.UIAxes_Contrast_Grayimage.Visible = true;
                app.UIAxes_Contrast_Advanceimage.Visible = true;
            end
        end
        
        % 重置几何变换相关组件
        function [] = reflush_transform(app,flag)
            if(~flag)
                app.Panel_Transform.Visible = false;
                app.Button_Transform_Confirm.Visible = false;
                app.UIAxes_Transform_Image.Visible = false;
                cla(app.UIAxes_Transform_Image);
                title(app.UIAxes_Transform_Image, '');
            else
                app.Panel_Transform.Visible = true;
                app.Button_Transform_Confirm.Visible = true;
                app.UIAxes_Transform_Image.Visible = true;
            end
        end
        
        % 重置噪声处理相关组件
        function []= reflush_filtering(app,flag) 
            if(~flag)
                app.Input_Filtering_Label.Visible = false;
                app.Input_Filtering.Visible = false;
                app.ButtonGroup_Filtering.Visible = false;
                app.ButtonGroup_Filtering_2.Visible = false;
                app.Button_Filtering_Confirm.Visible = false;
                app.UIAxes_Filtering_Noise.Visible = false;
                app.UIAxes_Filtering.Visible = false;
                cla(app.UIAxes_Filtering_Noise);
                title(app.UIAxes_Filtering, '');
                title(app.UIAxes_Filtering_Noise, '');
                cla(app.UIAxes_Filtering);
            else
                app.Input_Filtering_Label.Visible = true;
                app.Input_Filtering.Visible = true;
                app.ButtonGroup_Filtering.Visible = true;
                app.ButtonGroup_Filtering_2.Visible = true;
                app.Button_Filtering_Confirm.Visible = true;
                app.UIAxes_Filtering_Noise.Visible = true;
                app.UIAxes_Filtering.Visible = true;
            end
        end
        
        % 重置边缘处理相关组件
        function []= reflush_edge(app,flag) 
            if(~flag)
                app.UIAxes_Extract_Edge.Visible = false;
                app.UIAxes_Extract_Object.Visible = false;
                app.ButtonGroup_Extract_Edge.Visible = false;
                app.ButtonGroup_Extract_Obj.Visible = false;
                cla(app.UIAxes_Extract_Edge);
                cla(app.UIAxes_Extract_Object);
                title(app.UIAxes_Extract_Edge, '');
                title(app.UIAxes_Extract_Object, '');
            else
                app.UIAxes_Extract_Edge.Visible = true;
                app.UIAxes_Extract_Object.Visible = true;
                app.ButtonGroup_Extract_Edge.Visible = true;
                app.ButtonGroup_Extract_Obj.Visible = true;
            end
        end
        
        % 重置特征提取相关组件
        function []= reflush_feature(app,flag)
            if(~flag)
                app.UIAxes_Feature2.Visible = false;
                app.UIAxes_Feature.Visible = false;
                app.ButtonGroup_Feature.Visible = false;
                cla(app.UIAxes_Feature2);
                cla(app.UIAxes_Feature);
                title(app.UIAxes_Feature2, '');
                title(app.UIAxes_Feature, '');
            else
                app.UIAxes_Feature2.Visible = true;
                app.UIAxes_Feature.Visible = true;
                app.ButtonGroup_Feature.Visible = true;
            end
        end
        
        % 重置特征提取相关组件
        function []= reflush_ML(app,flag)
            if(~flag)
                app.TextArea_ML.Visible = false;
                app.Image_ML.Visible = false;
                app.TextArea_ML2.Visible = false;
            else
                app.TextArea_ML.Visible = true;
                app.Image_ML.Visible = true;
                app.TextArea_ML2.Visible = true;
            end
        end
        
        % 重置全局变量
        function [] = reflush_image(app)
            app.histogram = []; % 灰度直方图
            app.histogram_equalized = []; % 均衡化直方图
            app.histogram_perspected = []; % 规定化直方图
            app.img_equalized = []; % 均衡化图像
            app.img_perspected = []; % 规定化图像
        
            app.img_advancedlinear = []; % 线性变化增强图像
            app.img_advancedlog = []; % 对数变化增强图像
            app.img_advancedindex = []; % 指数变化增强图像_
        
            app.img_transformed = []; % 几何变换图像
        
            app.img_noise = []; % 噪声图像
            app.img_filtering = []; % 滤波图像
        
            app.img_edge = []; % 边缘提取
            app.img_obj = []; % 目标提取
        
            app.img_LBP = []; % 特征提取LBP
            app.img_HOG = []; % 特征提取HOG
            app.img_LBPedge = [];
            app.img_HOGedge = [];
        end
    end
    
    methods (Access = public)
        
        % 灰度处理
        function image_gray = to_gray(~,image) 
%             r = image(:,:,1);% 分颜色通道提取数据  
%             g = image(:,:,2);
%             b = image(:,:,3);
%             image_gray = 0.299*r + 0.587*g + 0.114*b;% 加权处理后累加
            image_gray = rgb2gray(image);
        end
        
        % 灰度直方图
        function histogram = to_histogram(~,gray_image)
            histogram = zeros(256);
            [h,w] = size(gray_image);
            for x = 1:w
                for y = 1:h
                    histogram(gray_image(y,x)+1) = histogram(gray_image(y,x)+1)+1;
                end
            end
        end
        
        % 直方图均衡化
        function equal_image = to_histogram_equalized(~,gray_image)
            histgram = imhist(gray_image);
            [h,w] = size(gray_image);
            New_Image = zeros(h,w);
            s = zeros(256);
            s(1) =histgram(1);
            
            for t = 2:256
                s(t) = s(t-1) + histgram(t); % 计算新的灰度值
            end
            
            for x = 1:w
                for y= 1:h
                    New_Image(y,x) = s(gray_image(y,x)+1) / (w*h); % 生成新图像
                end
            end
            New_Image = uint8(New_Image*255);
            
            equal_image = New_Image;
        end
        
        % 直方图规定化
        function persp_image = to_histogram_perspected(~,gray_image,ref_image)
            input_img = gray_image; 
            ref_img = ref_image;     
            
            % 计算输入图像和参考图像的直方图
            [input_counts, ~] = imhist(input_img, 256);
            [ref_counts, ref_bins] = imhist(ref_img, 256);
            
            % 累加直方图以得到累积分布函数
            input_cdf = cumsum(input_counts) / numel(input_img);
            ref_cdf = cumsum(ref_counts) / numel(ref_img);
            
            % 将输入图像的像素值映射到新的像素值
            table = zeros(1, 256);
            j = 1;
            for i = 0:255  
                while j <= 256 && ref_cdf(j) < input_cdf(i+1)
                    j = j + 1;
                end
                if j > 256
                    j = 256;  
                end
                table(i+1) = ref_bins(j-1);  
            end
            
            persp_image = uint8(table(input_img + 1)); 
        end
        
        % 线性变换对比度增强
        function advanced_image = to_enhanced_linear(~,gray_image)
            [h,w]=size(gray_image);
            advanced_image = zeros(h,w);
            % 规定线性系数
            a=100; b=200; mf=255;
            c=50; d=230; mg=255;
            
            for i = 1:h
                for j = 1:w
                    if(gray_image(i,j)<a && gray_image(i,j)>=0)
                        advanced_image(i,j) = round(c*gray_image(i,j)/a);
                    elseif(gray_image(i,j)<b && gray_image(i,j)>=a)
                        advanced_image(i,j) = round(((d-c)/(b-a))*(gray_image(i,j)-a)+c);
                    elseif(gray_image(i,j)<=mf && gray_image(i,j)>=b)
                        advanced_image(i,j) = round(((mg-d)/(mf-b))*(gray_image(i,j)-b)+d);
                    end
                end
            end
            advanced_image = uint8(advanced_image);
        end
        
        % 对数变换对比度增强
        function advanced_image = to_enhanced_log(~,gray_image)
            image = double(gray_image);
            advanced_image = log(image+1);
        end
        
        % 指数变换对比度增强
        function advanced_image = to_enhanced_index(~,gray_image)
            image = double(gray_image);
            advanced_image = exp(0.325 * (image-255)/30) + 1;
        end
        
        % 几何变换放缩
        function shrinked_image = to_transform_shrinked(~,image,m,n)
            imgR = image(:, :, 1);
            [h, w] = size(imgR);
            
            for t = 1:3
                for i = 1:h*m          %新高度，即行
                    for j = 1:w*n      %新宽度，即列
                        % 坐标转换
                        x = i/m;         
                        y = j/n;           
                        u = x-floor(x);  
                        v = y-floor(y);
                        % 边界处理
                        if x < 1           
                            x = 1;
                        end
                        if x > h
                            x = h;
                        end
                        if y < 1
                            y = 1;
                        end
                        if y > w
                            y = w;
                        end
                        %按权重进行双线性插值
                        shrinked(i,j,t) = image(floor(x), floor(y), t)*(1-u)*(1-v)+ ...
                                    image(floor(x), ceil(y), t)* (1-u) * v + ...
                                    image(ceil(x), floor(y), t)* u * (1-v) + ...
                                    image(ceil(x), ceil(y), t)* u * v;        
                    end
                end
            end
            shrinked_image = shrinked;
        end
        
        % 图像逆时针旋转
        function rotated_image = to_transform_rotated(app,image,angle)
            imSize = size(image);
            R = image( :, :, 1 );
            G = image( :, :, 2 );
            B = image( :, :, 3 );
            
            imR = ImRotation(app,R,angle);
            imG = ImRotation(app,G,angle);
            imB = ImRotation(app,B,angle);
            
            im = uint8(zeros(imSize));
            im( :, :, 1 ) = imR;
            im( :, :, 2 ) = imG;
            im( :, :, 3 ) = imB;
            rotated_image = im;
        end
        
        % 添加噪声
        function noise_image = to_filtering_noise(~,img,type,noise_intensity)
            switch type
                case 'gaussian'
                    noise_image = imnoise(uint8(img), 'gaussian', 0, noise_intensity);
                    disp("111")
                case 'salt'
                    noise_image = imnoise(uint8(img), 'salt & pepper', noise_intensity);
                case 'speckle'
                    noise_image = imnoise(uint8(img), 'speckle', noise_intensity);
            end
        end
        
        % 空间域滤波——双边滤波
        function filtering_image = to_filtered(~,img,window_size,sigma_color,sigma_space)
            % 初始化  
            img = double(img);  
            [height,width,channels] = size(img);  
            image_filter = zeros(height, width, channels);  
              
            % 计算滤波器的半径  
            radius = floor(window_size /2);  
              
            for i = 1:height  
                for j = 1:width  
                    % 定义滤浓器的范围  
                    i_min = max(i - radius, 1);  
                    i_max = min(i + radius, height);  
                    j_min = max(j - radius, 1);  
                    j_max = min(j + radius, width);  
                      
                    % 获取滤波器的窗口  
                    window = img(i_min:i_max,j_min:j_max,:);  
                      
                    % 计算趣色和空间距离规重  
                    color_dist = sum((window-img(i,j,:)).^2, 3);  
                    space_dist = (i_min:i_max)'*ones(1,length(j_min:j_max))- i;  
                    space_dist = space_dist .^2+(ones(length(i_min:i_max),1)*(j_min:j_max)- j).^2;  
                    space_dist = exp(-space_dist/(2*sigma_space ^2));  
                    weight = space_dist .*exp(-color_dist/(2 *sigma_color ^2));  
                      
                    % 对像素进行加极平均  
                    image_filter(i,j,:) = sum(sum(weight .* window,1),2)/sum(sum(weight,1),2);  
                end  
            end
            filtering_image = uint8(image_filter);
        end
    
        % 频域滤波——同态滤波
        function filtering_image = to_filtered2(~,I1)
            % 参数声明
            rH = 1;
            rL = 0.1;
            c = 0.2; 
            D0 = 0.2;
            R = double(I1(:, :, 1));
            G = double(I1(:, :, 2));
            B = double(I1(:, :, 3));
            
            % 对每个通道应用同态滤波
            % 对数变换
            R_log = log(R + 1);
            G_log = log(G + 1);
            B_log = log(B + 1);
            % 直接进行傅里叶变换
            R_fft = fft2(R_log);
            G_fft = fft2(G_log);
            B_fft = fft2(B_log);
            
            % 同态滤波函数
            [M, N] = size(R);
            deta_r = rH - rL;
            D = D0^2;
            m_mid = floor(M / 2); % 中心点坐标
            n_mid = floor(N / 2);
            
            H = zeros(M, N);
            for i = 1:M
                for j = 1:N
                    dis = ((i - m_mid)^2 + (j - n_mid)^2);
                    H(i, j) = deta_r * (1 - exp((-c) * (dis / D))) + rL;
                end
            end
            
            % 滤波
            R_filtered_fft = R_fft .* H;
            G_filtered_fft = G_fft .* H;
            B_filtered_fft = B_fft .* H;          
            % 反傅里叶变换
            R_filtered = real(ifft2(R_filtered_fft));
            G_filtered = real(ifft2(G_filtered_fft));
            B_filtered = real(ifft2(B_filtered_fft));
            % 指数化
            R_filtered = exp(R_filtered) - 1;
            G_filtered = exp(G_filtered) - 1;
            B_filtered = exp(B_filtered) - 1;
            % 归一化处理
            R_filtered = uint8(255 * mat2gray(R_filtered));
            G_filtered = uint8(255 * mat2gray(G_filtered));
            B_filtered = uint8(255 * mat2gray(B_filtered));
            
            % 合并通道
            filtering_image = cat(3, R_filtered, G_filtered, B_filtered);
        end
    
        % 边缘提取
        function edged_image = to_edge(~,grayI,type)
            switch type
                case "robert"
                    % 定义 Robert 算子
                    robert_x = [1 0; 0 -1];
                    robert_y = [0 1; -1 0];
                    % 应用 Robert 算子进行边缘检测
                    Ix = imfilter(double(grayI), robert_x, 'replicate');
                    Iy = imfilter(double(grayI), robert_y, 'replicate');
                    edged_image = sqrt(Ix.^2 + Iy.^2);
                    edged_image = uint8(255 * mat2gray(edged_image));
                case "prewitt"
                    % 定义 Prewitt 算子
                    prewitt_x = [1 0 -1; 1 0 -1; 1 0 -1];
                    prewitt_y = [1 1 1; 0 0 0; -1 -1 -1];
                    % 应用 Prewitt 算子进行边缘检测
                    Ix = imfilter(double(grayI), prewitt_x, 'replicate');
                    Iy = imfilter(double(grayI), prewitt_y, 'replicate');
                    edged_image = sqrt(Ix.^2 + Iy.^2);
                    edged_image = uint8(255 * mat2gray(edged_image)); 
                case "sobel"
                    % 定义 Sobel 算子
                    sobel_x = [-1 0 1; -2 0 2; -1 0 1];
                    sobel_y = [-1 -2 -1; 0 0 0; 1 2 1];
                    % 应用 Sobel 算子进行边缘检测
                    Ix = imfilter(double(grayI), sobel_x, 'replicate');
                    Iy = imfilter(double(grayI), sobel_y, 'replicate');
                    edged_image = sqrt(Ix.^2 + Iy.^2);
                    edged_image = uint8(255 * mat2gray(edged_image));
                case "laplacian"
                    % 定义拉普拉斯滤波器（离散形式）
                    laplacianFilter = [0 -1 0; -1 4 -1; 0 -1 0];
                    % 应用拉普拉斯滤波器进行边缘检测
                    edgesLaplacian = imfilter(double(grayI), laplacianFilter, 'replicate');
                    edgesLaplacian = abs(edgesLaplacian); 
                    edged_image = uint8(255 * mat2gray(edgesLaplacian));
            end
                   
        end
    
        % 自适应阈值法
        function obj_image = to_obj_adapt(~,gray_image)
            % 获取所有像素的自适应
            T = adaptthresh(gray_image,0.3,'NeighborhoodSize',2*floor(size(gray_image)/10)+1,"Statistic","gaussian");
            obj_image = imbinarize(gray_image,T);
        end
    
        % 模式分类阈值法
        function obj_image = to_obj_otsu(~,gray_image)
            T = graythresh(gray_image);
            obj_image = imbinarize(gray_image,T);
        end
        
        % 迭代阈值法
        function obj_image = to_obj_iterative(~,gray_image)
            gray_image = im2double(gray_image);
            T = (max(gray_image(:)) + min(gray_image(:)))/2;
            equal = false;
            while ~equal
                rb = find(gray_image >= T);
                ro = find(gray_image < T);
                NewT = ((mean(gray_image(rb))) + mean(gray_image(ro)))/2;
                equal = abs(NewT - T)<1/256;
                T = NewT;
            end
            obj_image = imbinarize(gray_image,T);
        end
        
        % 模糊熵阈值法
        function obj_image = to_obj_obscure(~,gray_image)
            Image = gray_image;
            hist = imhist(Image);
            bottom = min(Image(:))+1;
            top = max(Image(:))+1;
            
            S = zeros(256, 1);
            C = double(top- bottom);
            J =10^10;
            
            for t= bottom+ 1:top- 1
                range = double(bottom:t)';
                miuo = sum(range.* hist(range));
                pixelnum = sum(hist(range));
                miuo = miuo/pixelnum;
                miuf = 1./(1 + abs(double(range)-miuo)/C);
                S(range)= -miuf.* log(miuf)-(1 -miuf).* log(1-miuf);
                range = double(t+ 1:top)';
                miub = sum(range.* hist(range));
                
                pixelnum = sum(hist(range));
                miub = miub/pixelnum;
                miuf = 1./(1 + abs(double(range)- miub)/C);
                S(range) = - miuf.* log(miuf)-(1 - miuf).* log(1-miuf);
                currentJ = sum(hist(bottom:top).* S(bottom:top));
                if currentJ<J
                    J= currentJ;
                    thresh= t;
                end
            end
            
            obj_image = zeros(size(Image));
            obj_image(Image> thresh)= 1;
        end
      
        % 特征识别LBP
        function feature_image = to_featured_LBP(~,gray_image)
            image = gray_image;
            [N, M] = size(image);
            lbp = zeros(N, M);
            for j= 2:N-1
                for i= 2:M-1
                    neighbor = [j-1,i-1;j-1,i;j-1,i+1;j,i+1;j+1,i+1;j+1,i;j+1,i-1;j,i-1];
                    count = 0;
                    for k= 1:8
                        if image(neighbor(k, 1), neighbor(k, 2))> image(j, i)
                            count = count + 2^(8-k);
                        end
                    end
                    lbp(j, i) = count;
                end
            end
            feature_image = uint8(lbp);
        end
        
        % 特征识别HOG
        function feature_image = to_featured_HOG(~,gray_image)
            % 提取 HOG 特征和可视化信息
            % 'CellSize' 设置为 8x8 的单元格大小
            [~, feature_image] = extractHOGFeatures(gray_image, 'CellSize', [8,8]);
        end
        
        % 深度学习识别
        function [classes, classesId] = predict_with_onnx(app,onnx_model_name, confT)
            onnxruntime = py.importlib.import_module('onnxruntime');
            session = onnxruntime.InferenceSession(onnx_model_name); 
        
            classes = load_classes(app,'birds.yaml'); 
        
            % 读取图像并进行预处理
            image = app.img;
            img_data = preprocess_image(app,image);
        
            % 模型推理
            input_name = session.get_inputs{1}.name;
            img_data_py = py.numpy.array(img_data);  % 将 img_data 转换为 numpy 数组
            
            % 调用模型进行推理
            inputs_dict = py.dict(pyargs(input_name, img_data_py));  % 创建字典
            outputs = session.run(py.None, inputs_dict);  % 执行推理
            classesId = get_class_ids(app,outputs, confT);
        end
    
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button_Input
        function Button_InputPushed(app, event)
            % 获取文本框中的路径
            filePath = app.Input_Image.Value;
            % 检查路径是否为空或无效
            if isempty(filePath) || ~exist(filePath, 'file')
                uialert(app.UIFigure, 'Error', 'Please enter a valid file path.');
                return;
            end
            
            % 读取图片
            try
                image = imread(filePath);
            catch ME %#ok<NASGU> 
                uialert(app.UIFigure, 'Error', 'Failed to read the image file.');
                return;
            end
            
            % 存储原始图片
            reflush_image(app);% 清除原有临时存储
            app.img = image;
            app.img_gray = rgb2gray(image);
            % 在UIAxes上显示图片
            imshow(app.img, 'Parent', app.UIAxes_Image);
            title(app.UIAxes_Image, '原始图像');
        end

        % Selection changed function: ButtonGroup_Selection
        function ButtonGroup_SelectionSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Selection.SelectedObject;
            if(isempty(app.img) || isempty(app.img_gray))% 处理保护
                uialert(app.UIFigure, 'Error', 'Please enter the image file.')
            end
            reflush(app);
            
            switch selected_button
                case app.Histogram % 灰度直方图
                    reflush_histogram(app,1);      
                    
                case app.Contrast % 对比度增强
                    reflush_contrast(app,1);
                    
                case app.Transform % 几何变换
                    reflush_transform(app,1);
                
                case app.Filtering % 噪声处理
                    reflush_filtering(app,1);
                    
                case app.Extract % 物体提取
                    reflush_edge(app,1);
                    
                case app.Feature % 特征提取
                    reflush_feature(app,1);
                    
                case app.Mlrecognition % 图像识别
                    reflush_ML(app,1);
                    onnx_model_name = 'birds.onnx';
                    confT = 0.3;  % 置信度阈值
                    [classes,classesId] = predict_with_onnx(app,onnx_model_name,confT );
                    str1 = char(classes{classesId});
                    app.TextArea_ML2.Value = str1;
                    app.Image_ML.ImageSource = app.img;
            end
        end

        % Selection changed function: ButtonGroup_Histogram
        function ButtonGroup_HistogramSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Histogram.SelectedObject;
 
            switch selected_button
                case app.Histogram_Equalization % 直方图均衡化
                    if(isempty(app.histogram_equalized))
                        app.img_equalized = to_histogram_equalized(app,app.img_gray);
                        app.histogram_equalized = imhist(app.img_equalized);
                    end
                    
                    % 显示选项栏和图片
                    imshow(app.img_equalized, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '均衡化——灰度图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram_equalized)
                    title(app.UIAxes_Histogram_Histogram, '均衡化——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
                    
                case app.Histogram_Perspective % 直方图规定化
                    img_ref = rgb2gray(imread((app.Input_Histogram_ReferImage.Value)));
                    if(isempty(app.histogram_perspected))
                        app.img_perspected = to_histogram_perspected(app,app.img_gray,img_ref);
                        app.histogram_perspected = imhist(app.img_perspected);
                    end
                    % 显示选项栏和图片 
                    imshow(app.img_perspected, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '规定化——参考图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram_perspected);
                    title(app.UIAxes_Histogram_Histogram, '规定化——灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
                    
                case app.Histogram_Origin % 灰度直方图
                    if(isempty(app.histogram))
                        app.histogram = to_histogram(app,app.img_gray);
                    end
                    
                    % 显示选项栏和图片
                    imshow(app.img_gray, 'Parent', app.UIAxes_Histogram_Image);
                    title(app.UIAxes_Histogram_Image, '灰度图');
                    
                    stem(app.UIAxes_Histogram_Histogram, app.histogram)
                    title(app.UIAxes_Histogram_Histogram, '灰度直方图');
                    xlabel(app.UIAxes_Histogram_Histogram, '灰度值');
                    ylabel(app.UIAxes_Histogram_Histogram, '像素数量');
            end
            
        end

        % Selection changed function: ButtonGroup_Contrast
        function ButtonGroup_ContrastSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Contrast.SelectedObject;
            
            switch selected_button
                case app.Contrast_Linear % 线性
                    if(isempty(app.img_advancedlinear))
                        app.img_advancedlinear = to_enhanced_linear(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedlinear, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '线性增强——灰度图'); 
                    
                case app.Contrast_Log % 对数
                    if(isempty(app.img_advancedlog))
                        app.img_advancedlog = to_enhanced_log(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedlog, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '对数增强——灰度图'); 
                    
                case app.Contrast_Index % 指数
                    if(isempty(app.img_advancedindex))
                        app.img_advancedindex = to_enhanced_linear(app, app.img_gray);
                    end
                    
                    imshow(app.img_gray, 'Parent',app.UIAxes_Contrast_Grayimage);
                    title(app.UIAxes_Contrast_Grayimage, '灰度图');
                    imshow(app.img_advancedindex, [], 'Parent', app.UIAxes_Contrast_Advanceimage);
                    title(app.UIAxes_Contrast_Advanceimage, '指数增强——灰度图'); 
            end
            
        end

        % Button pushed function: Button_Transform_Confirm
        function Button_Transform_ConfirmPushed(app, event)
            m = app.Transform_Shrinkx.Value;
            n = app.Transform_Shrinky.Value;
            angle1 = app.Transform_Rotate1.Value;
            angle2 = app.Transform_Rotate2.Value;
            angle = (angle2-angle1) / 180;
            img_tr = to_transform_shrinked(app, app.img, m, n);
            app.img_transformed = to_transform_rotated(app, img_tr, angle);
            
            imshow(app.img_transformed, 'Parent', app.UIAxes_Transform_Image);
            title(app.UIAxes_Transform_Image, '图像———几何变换');
        end

        % Button pushed function: Button_Filtering_Confirm
        function Button_Filtering_ConfirmPushed(app, event)
            select_button = app.ButtonGroup_Filtering.SelectedObject;
            noise_intensity = app.Input_Filtering.Value;
            % 噪声处理
            switch select_button
                case app.Filtering_Gaussian
                    app.img_noise = to_filtering_noise(app,app.img,"gaussian",noise_intensity);
                case app.Filtering_Salt
                    app.img_noise = to_filtering_noise(app,app.img,"salt",noise_intensity);
                case app.Filtering_Speckle
                    app.img_noise = to_filtering_noise(app,app.img,"speckle",noise_intensity);
            end
            imshow(app.img_noise, 'Parent', app.UIAxes_Filtering_Noise);
            title(app.UIAxes_Filtering_Noise, '噪声图像');
            
            % 滤波处理
            select_button2 = app.ButtonGroup_Filtering_2.SelectedObject;
            switch select_button2
                case app.Filtering_Imbilatfile
                    app.img_filtering = to_filtered(app, app.img_noise, 15, 75, 75);
                case app.Filtering_Ffshift
                    app.img_filtering = to_filtered2(app, app.img_noise);
            end
            imshow(app.img_filtering, 'Parent', app.UIAxes_Filtering);
            title(app.UIAxes_Filtering, '滤波图像');
        end

        % Selection changed function: ButtonGroup_Extract_Edge
        function ButtonGroup_Extract_EdgeSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Extract_Edge.SelectedObject;
            % 边缘提取
            switch selected_button
                case app.RobertButton
                    app.img_edge = to_edge(app,app.img_gray,"robert");
                case app.PrewittButton
                    app.img_edge = to_edge(app,app.img_gray,"prewitt");
                case app.SobelButton
                    app.img_edge = to_edge(app,app.img_gray,"sobel");
                case app.LaplacianButton
                    app.img_edge = to_edge(app,app.img_gray,"laplacian");     
            end
            imshow(app.img_edge, 'Parent', app.UIAxes_Extract_Edge);
            title(app.UIAxes_Extract_Edge, '边缘提取');
        end

        % Selection changed function: ButtonGroup_Extract_Obj
        function ButtonGroup_Extract_ObjSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Extract_Obj.SelectedObject;
            switch selected_button
                case app.Obj_Adapt
                    app.img_obj = to_obj_adapt(app,app.img_gray);
                case app.Obj_Otsu
                    app.img_obj = to_obj_otsu(app, app.img_gray);
                case app.Obj_Iterative
                    app.img_obj = to_obj_iterative(app, app.img_gray);
                case app.Obj_Obscure
                    app.img_obj = to_obj_obscure(app, app.img_gray);
            end
            
            imshow(app.img_obj, 'Parent', app.UIAxes_Extract_Object);
            title(app.UIAxes_Extract_Object, '物体提取');
        end

        % Selection changed function: ButtonGroup_Feature
        function ButtonGroup_FeatureSelectionChanged(app, event)
            selected_button = app.ButtonGroup_Feature.SelectedObject;
            
            switch selected_button 
                case app.Feature_LBP
                    if (isempty(app.img_LBP))
                        app.img_LBP = to_featured_LBP(app,app.img_gray);
                        app.img_LBPedge = to_featured_LBP(app,app.img_edge);
                    end
                    
                    imshow(app.img_LBP, 'Parent', app.UIAxes_Feature);
                    title(app.UIAxes_Feature, '特征提取');
                    imshow(app.img_LBPedge, 'Parent', app.UIAxes_Feature2);
                    title(app.UIAxes_Feature2, '边缘提取——特征提取');
                case app.Feature_HOG
                    if (isempty(app.img_HOG))
                        app.img_HOG = to_featured_HOG(app,app.img_gray);
                        app.img_HOGedge = to_featured_HOG(app,app.img_edge);
                    end
                    imshow(app.img_HOG, 'Parent', app.UIAxes_Feature);
                    title(app.UIAxes_Feature, '特征提取');
                    imshow(app.img_HOGedge, 'Parent', app.UIAxes_Feature2);
                    title(app.UIAxes_Feature2, '边缘提取——特征提取');
            end     
        end

        % Clicked callback: PushTool
        function PushToolClicked(app, event)
            % 检查灰度图像是否非空，并保存为文件
            if ~isempty(app.img_gray)
                imwrite(app.img_gray, fullfile(app.OutputFolder, 'gray_image.png')); % 保存为 PNG 文件到指定文件夹
                disp('灰度图像已保存。');
            end
        
            % 检查均衡化图像是否非空，并保存为文件
            if ~isempty(app.img_equalized)
                imwrite(app.img_equalized, fullfile(app.OutputFolder, 'equalized_image.png'));
                disp('均衡化图像已保存。');
            end
        
            % 检查规定化（直方图匹配）图像是否非空，并保存为文件
            if ~isempty(app.img_perspected)
                imwrite(app.img_perspected, fullfile(app.OutputFolder, 'perspected_image.png'));
                disp('规定化图像已保存。');
            end
        
            % 检查线性变换增强图像是否非空，并保存为文件
            if ~isempty(app.img_advancedlinear)
                imwrite(app.img_advancedlinear, fullfile(app.OutputFolder, 'linear_enhanced_image.png'));
                disp('线性变换增强图像已保存。');
            end
        
            % 检查对数变换增强图像是否非空，并保存为文件
            if ~isempty(app.img_advancedlog)
                imwrite(app.img_advancedlog, fullfile(app.OutputFolder, 'log_enhanced_image.png'));
                disp('对数变换增强图像已保存。');
            end
        
            % 检查指数变换增强图像是否非空，并保存为文件
            if ~isempty(app.img_advancedindex)
                imwrite(app.img_advancedindex, fullfile(app.OutputFolder, 'exp_enhanced_image.png'));
                disp('指数变换增强图像已保存。');
            end
        
            % 检查几何变换图像是否非空，并保存为文件
            if ~isempty(app.img_transformed)
                imwrite(app.img_transformed, fullfile(app.OutputFolder, 'transformed_image.png'));
                disp('几何变换图像已保存。');
            end
        
            % 检查噪声图像是否非空，并保存为文件
            if ~isempty(app.img_noise)
                imwrite(app.img_noise, fullfile(app.OutputFolder, 'noise_image.png'));
                disp('噪声图像已保存。');
            end
        
            % 检查滤波图像是否非空，并保存为文件
            if ~isempty(app.img_filtering)
                imwrite(app.img_filtering, fullfile(app.OutputFolder, 'filtered_image.png'));
                disp('滤波图像已保存。');
            end
        
            % 检查边缘提取图像是否非空，并保存为文件
            if ~isempty(app.img_edge)
                imwrite(app.img_edge, fullfile(app.OutputFolder, 'edge_image.png'));
                disp('边缘提取图像已保存。');
            end
        
            % 检查目标提取图像是否非空，并保存为文件
            if ~isempty(app.img_obj)
                imwrite(app.img_obj, fullfile(app.OutputFolder, 'object_image.png'));
                disp('目标提取图像已保存。');
            end
        
            if ~isempty(app.img_LBP)
                imwrite(app.img_LBP, fullfile(app.OutputFolder, 'LBP_image.png'));
                disp('LBP特征图像已保存。');
            end
            
            if ~isempty(app.img_HOG)
                imwrite(app.img_HOG, fullfile(app.OutputFolder, 'HOG_image.png'));
                disp('HOG特征图像已保存。');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.UIAxes_Feature2 = uiaxes(app.UIFigure);
            app.UIAxes_Feature2.XTick = [];
            app.UIAxes_Feature2.YTick = [];
            app.UIAxes_Feature2.Visible = 'off';
            app.UIAxes_Feature = uiaxes(app.UIFigure);
            app.UIAxes_Feature.XTick = [];
            app.UIAxes_Feature.YTick = [];
            app.UIAxes_Feature.Visible = 'off';
            app.UIAxes_Extract_Object = uiaxes(app.UIFigure);
            app.UIAxes_Extract_Object.XTick = [];
            app.UIAxes_Extract_Object.YTick = [];
            app.UIAxes_Extract_Object.Visible = 'off';
            app.UIAxes_Extract_Edge = uiaxes(app.UIFigure);
            app.UIAxes_Extract_Edge.XTick = [];
            app.UIAxes_Extract_Edge.YTick = [];
            app.UIAxes_Extract_Edge.Visible = 'off';
            app.UIAxes_Filtering = uiaxes(app.UIFigure);
            app.UIAxes_Filtering.XTick = [];
            app.UIAxes_Filtering.YTick = [];
            app.UIAxes_Filtering.Visible = 'off';
            app.UIAxes_Filtering_Noise = uiaxes(app.UIFigure);
            app.UIAxes_Filtering_Noise.XTick = [];
            app.UIAxes_Filtering_Noise.YTick = [];
            app.UIAxes_Filtering_Noise.Visible = 'off';
            app.UIAxes_Transform_Image = uiaxes(app.UIFigure);
            app.UIAxes_Transform_Image.XTick = [];
            app.UIAxes_Transform_Image.YTick = [];
            app.UIAxes_Transform_Image.Visible = 'off';
            app.UIAxes_Contrast_Advanceimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Advanceimage, '变换后灰度图')
            app.UIAxes_Contrast_Advanceimage.XTick = [];
            app.UIAxes_Contrast_Advanceimage.YTick = [];
            app.UIAxes_Contrast_Advanceimage.Visible = 'off';
            app.UIAxes_Contrast_Grayimage = uiaxes(app.UIFigure);
            title(app.UIAxes_Contrast_Grayimage, '灰度图')
            app.UIAxes_Contrast_Grayimage.XTick = [];
            app.UIAxes_Contrast_Grayimage.YTick = [];
            app.UIAxes_Contrast_Grayimage.Visible = 'off';
            app.UIAxes_Histogram_Histogram = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Histogram, '直方图')
            app.UIAxes_Histogram_Histogram.XTick = [];
            app.UIAxes_Histogram_Histogram.YTick = [];
            app.UIAxes_Histogram_Histogram.Visible = 'off';
            app.UIAxes_Histogram_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Histogram_Image, '灰度图')
            app.UIAxes_Histogram_Image.XTick = [];
            app.UIAxes_Histogram_Image.YTick = [];
            app.UIAxes_Histogram_Image.Visible = 'off';
            app.UIAxes_Image = uiaxes(app.UIFigure);
            title(app.UIAxes_Image, '原图')
            app.UIAxes_Image.XTick = [];
            app.UIAxes_Image.YTick = [];
            app.Input_Filtering = uieditfield(app.UIFigure, 'numeric');
            app.Input_Filtering.Visible = 'off';
            app.Input_Filtering_Label = uieditfield(app.UIFigure, 'text');
            app.Input_Filtering_Label.Visible = 'off';
            app.Input_Filtering_Label.Value = '密度：';
            app.Input_Histogram_ReferImage = uieditfield(app.UIFigure, 'text');
            app.Input_Histogram_ReferImage.Visible = 'off';
            app.Input_Histogram_ReferImage.Placeholder = '请输入参照图片地址……';
            app.ButtonGroup_Feature = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Feature.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_FeatureSelectionChanged, true);
            app.ButtonGroup_Feature.Title = '提取方法：';
            app.ButtonGroup_Feature.Visible = 'off';
            app.Feature_HOG = uiradiobutton(app.ButtonGroup_Feature);
            app.Feature_HOG.Text = 'HOG';
            app.Feature_HOG.Value = true;
            app.Feature_LBP = uiradiobutton(app.ButtonGroup_Feature);
            app.Feature_LBP.Text = 'LBP';
            app.ButtonGroup_Extract_Edge = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Extract_Edge.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Extract_EdgeSelectionChanged, true);
            app.ButtonGroup_Extract_Edge.Title = '算子';
            app.ButtonGroup_Extract_Edge.Visible = 'off';
            app.RobertButton = uiradiobutton(app.ButtonGroup_Extract_Edge);
            app.RobertButton.Text = 'Robert';
            app.RobertButton.Value = true;
            app.PrewittButton = uiradiobutton(app.ButtonGroup_Extract_Edge);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uiradiobutton(app.ButtonGroup_Extract_Edge);
            app.SobelButton.Text = 'Sobel';
            app.LaplacianButton = uiradiobutton(app.ButtonGroup_Extract_Edge);
            app.LaplacianButton.Text = 'Laplacian';
            app.Button_Filtering_Confirm = uibutton(app.UIFigure, 'push');
            app.Button_Filtering_Confirm.ButtonPushedFcn = createCallbackFcn(app, @Button_Filtering_ConfirmPushed, true);
            app.Button_Filtering_Confirm.Visible = 'off';
            app.Button_Filtering_Confirm.Text = '确认';
            app.Input_Image = uieditfield(app.UIFigure, 'text');
            app.ButtonGroup_Extract_Obj = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Extract_Obj.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_Extract_ObjSelectionChanged, true);
            app.ButtonGroup_Extract_Obj.Title = '阈值';
            app.ButtonGroup_Extract_Obj.Visible = 'off';
            app.Obj_Obscure = uiradiobutton(app.ButtonGroup_Extract_Obj);
            app.Obj_Obscure.Text = '模糊熵';
            app.Obj_Iterative = uiradiobutton(app.ButtonGroup_Extract_Obj);
            app.Obj_Iterative.Text = '迭代运算';
            app.Obj_Otsu = uiradiobutton(app.ButtonGroup_Extract_Obj);
            app.Obj_Otsu.Text = 'OTSU';
            app.Obj_Adapt = uiradiobutton(app.ButtonGroup_Extract_Obj);
            app.Obj_Adapt.Text = '自适应';
            app.Obj_Adapt.Value = true;
            app.Button_Input = uibutton(app.UIFigure, 'push');
            app.Button_Input.ButtonPushedFcn = createCallbackFcn(app, @Button_InputPushed, true);
            app.Button_Input.Text = '上传';
            app.ButtonGroup_Filtering_2 = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Filtering_2.Title = '噪声处理：';
            app.ButtonGroup_Filtering_2.Visible = 'off';
            app.Filtering_Ffshift = uiradiobutton(app.ButtonGroup_Filtering_2);
            app.Filtering_Ffshift.Text = '频域';
            app.Filtering_Imbilatfile = uiradiobutton(app.ButtonGroup_Filtering_2);
            app.Filtering_Imbilatfile.Text = '空域';
            app.Filtering_Imbilatfile.Value = true;
            app.ButtonGroup_Filtering = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Filtering.Title = '噪声选择：';
            app.ButtonGroup_Filtering.Visible = 'off';
            app.Filtering_Speckle = uiradiobutton(app.ButtonGroup_Filtering);
            app.Filtering_Speckle.Text = '泊松噪声';
            app.Filtering_Salt = uiradiobutton(app.ButtonGroup_Filtering);
            app.Filtering_Salt.Text = '椒盐噪声';
            app.Filtering_Gaussian = uiradiobutton(app.ButtonGroup_Filtering);
            app.Filtering_Gaussian.Text = '高斯噪声';
            app.Filtering_Gaussian.Value = true;
            app.Button_Transform_Confirm = uibutton(app.UIFigure, 'push');
            app.Button_Transform_Confirm.ButtonPushedFcn = createCallbackFcn(app, @Button_Transform_ConfirmPushed, true);
            app.Button_Transform_Confirm.Visible = 'off';
            app.Button_Transform_Confirm.Text = '确认';
            app.Panel_Transform = uipanel(app.UIFigure);
            app.Panel_Transform.Title = '输入：';
            app.Panel_Transform.Visible = 'off';
            app.EditField_4 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = '逆时针旋转：';
            app.EditField_3 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = '顺时针旋转：';
            app.EditField_2 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = '列缩放倍数：';
            app.EditField_1 = uieditfield(app.Panel_Transform, 'text');
            app.EditField_1.Editable = 'off';
            app.EditField_1.Value = '行缩放倍数：';
            app.Transform_Rotate2 = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Rotate1 = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinky = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinky.Value = 1;
            app.Transform_Shrinkx = uieditfield(app.Panel_Transform, 'numeric');
            app.Transform_Shrinkx.Value = 1;
            app.Input_Label = uilabel(app.UIFigure);
            app.Input_Label.Text = '图片地址：';
            app.ButtonGroup_Histogram = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Histogram.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_HistogramSelectionChanged, true);
            app.ButtonGroup_Histogram.Title = '选择：';
            app.ButtonGroup_Histogram.Visible = 'off';
            app.Histogram_Perspective = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Perspective.Tag = 'Equalization';
            app.Histogram_Perspective.Text = '直方图规定化';
            app.Histogram_Equalization = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Equalization.Tag = 'Equalization';
            app.Histogram_Equalization.Text = '直方图均衡化';
            app.Histogram_Origin = uitogglebutton(app.ButtonGroup_Histogram);
            app.Histogram_Origin.Tag = 'Origin';
            app.Histogram_Origin.Text = '灰度图';
            app.Histogram_Origin.Value = true;
            app.ButtonGroup_Contrast = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Contrast.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_ContrastSelectionChanged, true);
            app.ButtonGroup_Contrast.Title = '选择：';
            app.ButtonGroup_Contrast.Visible = 'off';
            app.Contrast_Index = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Index.Tag = 'Equalization';
            app.Contrast_Index.Text = '指数变换';
            app.Contrast_Log = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Log.Tag = 'Equalization';
            app.Contrast_Log.Text = '对数变换';
            app.Contrast_Linear = uitogglebutton(app.ButtonGroup_Contrast);
            app.Contrast_Linear.Tag = 'Origin';
            app.Contrast_Linear.Text = '线性处理';
            app.Contrast_Linear.Value = true;
            app.ButtonGroup_Selection = uibuttongroup(app.UIFigure);
            app.ButtonGroup_Selection.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroup_SelectionSelectionChanged, true);
            app.ButtonGroup_Selection.Title = '选择处理方式：';
            app.Mlrecognition = uitogglebutton(app.ButtonGroup_Selection);
            app.Mlrecognition.Text = 'ML识别';
            app.Feature = uitogglebutton(app.ButtonGroup_Selection);
            app.Feature.Text = '特征提取';
            app.Extract = uitogglebutton(app.ButtonGroup_Selection);
            app.Extract.Text = '物体提取';
            app.Filtering = uitogglebutton(app.ButtonGroup_Selection);
            app.Filtering.Text = '噪声处理';
            app.Transform = uitogglebutton(app.ButtonGroup_Selection);
            app.Transform.Text = '几何变换';
            app.Contrast = uitogglebutton(app.ButtonGroup_Selection);
            app.Contrast.Text = '对比度增强';
            app.Histogram = uitogglebutton(app.ButtonGroup_Selection);
            app.Histogram.Text = {'灰度直方图'; ''};
            app.Histogram.Value = true;
            app.Image_ML = uiimage(app.UIFigure);
            app.Image_ML.Visible = 'off';
            app.TextArea_ML = uitextarea(app.UIFigure);
            app.TextArea_ML.Editable = 'off';
            app.TextArea_ML.Visible = 'off';
            app.TextArea_ML.Value = {'调用Yolov8模型，置信度阈值0.3：'};
            app.TextArea_ML2 = uitextarea(app.UIFigure);
            app.TextArea_ML2.Editable = 'off';
            app.TextArea_ML2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end